classdef SAXSRedundancy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAXSRedundancyUIFigure          matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        EigerMasterMenu                 matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ProfileTab                      matlab.ui.container.Tab
        ProfileUIAxes                   matlab.ui.control.UIAxes
        ImageTab                        matlab.ui.container.Tab
        ImageUIAxes                     matlab.ui.control.UIAxes
        MaskControlTab                  matlab.ui.container.Tab
        EffectiveMaskPreviewUIAxes      matlab.ui.control.UIAxes
        CSVMaskUITable                  matlab.ui.control.Table
        MaskControlTabGroup             matlab.ui.container.TabGroup
        CSVMaskControlTab               matlab.ui.container.Tab
        AddCSVMaskButton                matlab.ui.control.Button
        RemoveCSVMaskButton             matlab.ui.control.Button
        MaskIDDropDownLabel             matlab.ui.control.Label
        CSVMaskIDDropDown               matlab.ui.control.DropDown
        ActiveCSVMaskButton             matlab.ui.control.Button
        InvertCSVMaskButton             matlab.ui.control.Button
        MaskGeneratorTab                matlab.ui.container.Tab
        MaskPreviewUIAxes               matlab.ui.control.UIAxes
        ExperimentalConditionsTab       matlab.ui.container.Tab
        ParametersinProcessButtonGroup  matlab.ui.container.ButtonGroup
        DefaultButton                   matlab.ui.control.RadioButton
        UserDefineButton                matlab.ui.control.RadioButton
        ExpCondUITable                  matlab.ui.control.Table
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        Tab2_2                          matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        DataSheetSelectionTab           matlab.ui.container.Tab
        CurrentDataSheetSpinnerLabel    matlab.ui.control.Label
        CurrentDataSheetSpinner         matlab.ui.control.Spinner
        ReDrawButton                    matlab.ui.control.Button
        AverageDataTab                  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        CurrentData % Save data for ploting
        PlotHandles % Handles for plots
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GeneralFunc.PresetVariables(app);
            GeneralFunc.UpdateCSVMaskTable(app,[]);
        end

        % Menu selected function: EigerMasterMenu
        function EigerMasterMenuSelected(app, event)
            UserCallback.EigerMasterImport(app,event);
        end

        % Value changed function: CurrentDataSheetSpinner
        function CurrentDataSheetSpinnerValueChanged(app, event)
            % value = app.CurrentDataSheetSpinner.Value;
            UserCallback.DataSheetSelect(app,event);
        end

        % Selection changed function: ParametersinProcessButtonGroup
        function ParametersinProcessButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ParametersinProcessButtonGroup.SelectedObject;
            GeneralFunc.UpdateMasterInfoFromTable(app,event);
        end

        % Cell edit callback: ExpCondUITable
        function ExpCondUITableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            GeneralFunc.UpdateMasterInfoFromTable(app, event);      
        end

        % Button pushed function: AddCSVMaskButton
        function AddCSVMaskButtonPushed(app, event)
            UserCallback.CSVMaskControl(app, event);
        end

        % Button pushed function: RemoveCSVMaskButton
        function RemoveCSVMaskButtonPushed(app, event)
            UserCallback.CSVMaskControl(app, event);
        end

        % Button pushed function: ActiveCSVMaskButton
        function ActiveCSVMaskButtonPushed(app, event)
            UserCallback.CSVMaskControl(app, event);
        end

        % Button pushed function: InvertCSVMaskButton
        function InvertCSVMaskButtonPushed(app, event)
            UserCallback.CSVMaskControl(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAXSRedundancyUIFigure = uifigure('Visible', 'off');
            app.SAXSRedundancyUIFigure.Name = 'SAXSRedundancy';
            app.ImportMenu = uimenu(app.SAXSRedundancyUIFigure);
            app.ImportMenu.Text = 'Import';
            app.EigerMasterMenu = uimenu(app.ImportMenu);
            app.EigerMasterMenu.MenuSelectedFcn = createCallbackFcn(app, @EigerMasterMenuSelected, true);
            app.EigerMasterMenu.Text = 'Eiger Master';
            app.ExportMenu = uimenu(app.SAXSRedundancyUIFigure);
            app.ExportMenu.Text = 'Export';
            app.TabGroup = uitabgroup(app.SAXSRedundancyUIFigure);
            app.ProfileTab = uitab(app.TabGroup);
            app.ProfileTab.Title = 'Profile';
            app.ProfileUIAxes = uiaxes(app.ProfileTab);
            title(app.ProfileUIAxes, 'Title')
            xlabel(app.ProfileUIAxes, 'X')
            ylabel(app.ProfileUIAxes, 'Y')
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImageUIAxes = uiaxes(app.ImageTab);
            title(app.ImageUIAxes, 'Title')
            xlabel(app.ImageUIAxes, 'X')
            ylabel(app.ImageUIAxes, 'Y')
            app.MaskControlTab = uitab(app.TabGroup);
            app.MaskControlTab.Title = 'Mask Control';
            app.EffectiveMaskPreviewUIAxes = uiaxes(app.MaskControlTab);
            title(app.EffectiveMaskPreviewUIAxes, 'Title')
            xlabel(app.EffectiveMaskPreviewUIAxes, 'X')
            ylabel(app.EffectiveMaskPreviewUIAxes, 'Y')
            app.CSVMaskUITable = uitable(app.MaskControlTab);
            app.CSVMaskUITable.ColumnName = {'Name'; 'Active'; 'Inverse'};
            app.MaskControlTabGroup = uitabgroup(app.MaskControlTab);
            app.CSVMaskControlTab = uitab(app.MaskControlTabGroup);
            app.CSVMaskControlTab.Title = 'CSV Mask Control';
            app.AddCSVMaskButton = uibutton(app.CSVMaskControlTab, 'push');
            app.AddCSVMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddCSVMaskButtonPushed, true);
            app.AddCSVMaskButton.Text = 'Add';
            app.RemoveCSVMaskButton = uibutton(app.CSVMaskControlTab, 'push');
            app.RemoveCSVMaskButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCSVMaskButtonPushed, true);
            app.RemoveCSVMaskButton.Text = 'Remove';
            app.MaskIDDropDownLabel = uilabel(app.CSVMaskControlTab);
            app.MaskIDDropDownLabel.Text = 'Mask ID';
            app.CSVMaskIDDropDown = uidropdown(app.CSVMaskControlTab);
            app.CSVMaskIDDropDown.Items = {'1.', '2.', '3.', '4.', '5.', '6.', '7.', '8.', '9.', '10.'};
            app.CSVMaskIDDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.CSVMaskIDDropDown.Value = '1';
            app.ActiveCSVMaskButton = uibutton(app.CSVMaskControlTab, 'push');
            app.ActiveCSVMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ActiveCSVMaskButtonPushed, true);
            app.ActiveCSVMaskButton.Text = 'Active / Inactive';
            app.InvertCSVMaskButton = uibutton(app.CSVMaskControlTab, 'push');
            app.InvertCSVMaskButton.ButtonPushedFcn = createCallbackFcn(app, @InvertCSVMaskButtonPushed, true);
            app.InvertCSVMaskButton.Text = 'Invert / Normal';
            app.MaskGeneratorTab = uitab(app.TabGroup);
            app.MaskGeneratorTab.Title = 'Mask Generator';
            app.MaskPreviewUIAxes = uiaxes(app.MaskGeneratorTab);
            title(app.MaskPreviewUIAxes, 'Title')
            xlabel(app.MaskPreviewUIAxes, 'X')
            ylabel(app.MaskPreviewUIAxes, 'Y')
            app.ExperimentalConditionsTab = uitab(app.TabGroup);
            app.ExperimentalConditionsTab.Title = 'Experimental Conditions';
            app.ParametersinProcessButtonGroup = uibuttongroup(app.ExperimentalConditionsTab);
            app.ParametersinProcessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParametersinProcessButtonGroupSelectionChanged, true);
            app.ParametersinProcessButtonGroup.Title = 'Parameters in Process';
            app.DefaultButton = uiradiobutton(app.ParametersinProcessButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.UserDefineButton = uiradiobutton(app.ParametersinProcessButtonGroup);
            app.UserDefineButton.Text = 'User Define';
            app.ExpCondUITable = uitable(app.ExperimentalConditionsTab);
            app.ExpCondUITable.ColumnName = {'Parameter'; 'Current Setting'; 'Default'; 'User Define'};
            app.ExpCondUITable.RowName = {};
            app.ExpCondUITable.ColumnEditable = [false false false true];
            app.ExpCondUITable.CellEditCallback = createCallbackFcn(app, @ExpCondUITableCellEdit, true);
            app.TabGroup2 = uitabgroup(app.SAXSRedundancyUIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            app.TabGroup3 = uitabgroup(app.SAXSRedundancyUIFigure);
            app.DataSheetSelectionTab = uitab(app.TabGroup3);
            app.DataSheetSelectionTab.Title = 'Data Sheet Selection';
            app.CurrentDataSheetSpinnerLabel = uilabel(app.DataSheetSelectionTab);
            app.CurrentDataSheetSpinnerLabel.Text = 'Current Data Sheet';
            app.CurrentDataSheetSpinner = uispinner(app.DataSheetSelectionTab);
            app.CurrentDataSheetSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentDataSheetSpinnerValueChanged, true);
            app.ReDrawButton = uibutton(app.DataSheetSelectionTab, 'push');
            app.ReDrawButton.Text = 'ReDraw';
            app.AverageDataTab = uitab(app.TabGroup3);
            app.AverageDataTab.Title = 'Average Data';
            % Show the figure after all components are created
            app.SAXSRedundancyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAXSRedundancy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAXSRedundancyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAXSRedundancyUIFigure)
        end
    end
end